classdef make_toml < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GeneratesaJSONfilethatwillthenLabel  matlab.ui.control.Label
        AddleftimplantationButton       matlab.ui.control.Button
        AddleftinjectionButton          matlab.ui.control.Button
        AddChannelDetailstoLeftButton   matlab.ui.control.Button
        AddChannelDetailstoRightButton  matlab.ui.control.Button
        RedCarrierEditField             matlab.ui.control.NumericEditField
        RedCarrierEditFieldLabel        matlab.ui.control.Label
        GreenCarrierEditField           matlab.ui.control.NumericEditField
        GreenCarrierEditFieldLabel      matlab.ui.control.Label
        RedPhotomEditField              matlab.ui.control.NumericEditField
        RedPhotomEditFieldLabel         matlab.ui.control.Label
        GreenPhotomEditField            matlab.ui.control.NumericEditField
        GreenPhotomEditFieldLabel       matlab.ui.control.Label
        SignalIndiceszeroindexednone1Label  matlab.ui.control.Label
        RedemissionwavelengthEditField  matlab.ui.control.NumericEditField
        RedemissionwavelengthEditFieldLabel  matlab.ui.control.Label
        RedexcitationwavelengthEditField  matlab.ui.control.NumericEditField
        RedexcitationwavelengthEditFieldLabel  matlab.ui.control.Label
        GreenemissionwavelengthEditField  matlab.ui.control.NumericEditField
        GreenemissionwavelengthEditFieldLabel  matlab.ui.control.Label
        GreenexcitationwavelengthEditField  matlab.ui.control.NumericEditField
        GreenexcitationwavelengthEditFieldLabel  matlab.ui.control.Label
        TotalchannelsEditField          matlab.ui.control.NumericEditField
        TotalchannelsEditFieldLabel     matlab.ui.control.Label
        ChannelDetailsLabel             matlab.ui.control.Label
        AddtoLeftHemisphereButton       matlab.ui.control.Button
        AddtoRightHemisphereButton      matlab.ui.control.Button
        RedcarrierfreqEditField         matlab.ui.control.NumericEditField
        RedcarrierfreqEditFieldLabel    matlab.ui.control.Label
        GreencarrierfreqEditField       matlab.ui.control.NumericEditField
        GreencarrierfreqEditFieldLabel  matlab.ui.control.Label
        BandpassBWDropDown              matlab.ui.control.DropDown
        BandpassBWDropDownLabel         matlab.ui.control.Label
        NopersegmentDropDown            matlab.ui.control.DropDown
        NopersegmentDropDownLabel       matlab.ui.control.Label
        TransformDropDown               matlab.ui.control.DropDown
        TransformDropDownLabel          matlab.ui.control.Label
        ZwindowEditField                matlab.ui.control.NumericEditField
        ZwindowEditFieldLabel           matlab.ui.control.Label
        DownsamplingHzEditField         matlab.ui.control.NumericEditField
        DownsamplingHzEditFieldLabel    matlab.ui.control.Label
        SamplingHzEditField             matlab.ui.control.NumericEditField
        SamplingHzEditFieldLabel        matlab.ui.control.Label
        TimeoffsetsEditField            matlab.ui.control.NumericEditField
        TimeoffsetsEditFieldLabel       matlab.ui.control.Label
        ProcessingParamatersLabel       matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        FiberDVEditField                matlab.ui.control.NumericEditField
        FiberDVEditFieldLabel           matlab.ui.control.Label
        FiberMLEditField                matlab.ui.control.NumericEditField
        FiberMLEditFieldLabel           matlab.ui.control.Label
        FiberAPEditField                matlab.ui.control.NumericEditField
        FiberAPEditFieldLabel           matlab.ui.control.Label
        AddrightimplantationButton      matlab.ui.control.Button
        RegionEditField                 matlab.ui.control.EditField
        RegionEditFieldLabel            matlab.ui.control.Label
        AddrightinjectionButton         matlab.ui.control.Button
        sabatinidatajointmetafilegeneratorLabel  matlab.ui.control.Label
        FiberImplantationLabel          matlab.ui.control.Label
        VirusInjectionLabel             matlab.ui.control.Label
        DVEditField                     matlab.ui.control.NumericEditField
        DVEditFieldLabel                matlab.ui.control.Label
        MLEditField                     matlab.ui.control.NumericEditField
        MLEditFieldLabel                matlab.ui.control.Label
        APEditField                     matlab.ui.control.NumericEditField
        APEditFieldLabel                matlab.ui.control.Label
        VolumenLEditField               matlab.ui.control.NumericEditField
        VolumenLEditFieldLabel          matlab.ui.control.Label
        SensorProteinDropDown           matlab.ui.control.DropDown
        SensorProteinDropDownLabel      matlab.ui.control.Label
        ProteinDropDown                 matlab.ui.control.DropDown
        ProteinDropDownLabel            matlab.ui.control.Label
        RecombinaseDependencyDropDown   matlab.ui.control.DropDown
        RecombinaseDependencyDropDownLabel  matlab.ui.control.Label
        SerotypeDropDown                matlab.ui.control.DropDown
        SerotypeDropDownLabel           matlab.ui.control.Label
        SurgeryDateDatePicker           matlab.ui.control.DatePicker
        SurgeryDateDatePickerLabel      matlab.ui.control.Label
        SurgeonEditField                matlab.ui.control.EditField
        SurgeonEditFieldLabel           matlab.ui.control.Label
        CreateButton                    matlab.ui.control.Button
        ParadigmEditField               matlab.ui.control.EditField
        ParadigmEditFieldLabel          matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        ExperimenterEditField           matlab.ui.control.EditField
        ExperimenterEditFieldLabel      matlab.ui.control.Label
        SessionEditField                matlab.ui.control.EditField
        SessionEditFieldLabel           matlab.ui.control.Label
        SubjectEditField                matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        Experimenter = '';
        subject = 'animalID';
        session = '';
        Date = '';
        paradigm = '';
        surgeon = '';
        surgeryDate = '';
        volume = 0;
        diameter = '';
        dT = 0;
        sampleFreq = 2000;
        downsampleFreq = 200;
        z = 60;
        transform = 'spectrogram';
        Noperseg = 216;
        NoOverlap = 108;
        BP = 60;
        total_chan = 8;
        rightSignal = struct();
        leftSignal = struct();
        rightInjection = struct();
        leftInjection = struct();
        rightImplant = struct();
        leftImplant = struct();
        carrierRight = struct();
        carrierLeft = struct();
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SerotypeDropDown
        function SerotypeDropDownValueChanged(app, event)

            app.SerotypeDropDown.Editable = 'on';
        end

        % Value changed function: ProteinDropDown
        function ProteinDropDownValueChanged(app, event)
           
            app.ProteinDropDown.Editable = 'on';
        end

        % Value changed function: RecombinaseDependencyDropDown
        function RecombinaseDependencyDropDownValueChanged(app, event)
            app.RecombinaseDependencyDropDown.Editable = 'on';
        end

        % Value changed function: SensorProteinDropDown
        function SensorProteinDropDownValueChanged(app, event)
            app.SensorProteinDropDown.Editable = 'on';
        end

        % Value changed function: TransformDropDown
        function TransformDropDownValueChanged(app, event)
          value = app.TransformDropDown.Value;
          switch value 
            case 'spectrogram'
                app.NopersegmentDropDown.Visible = 'on';   % Set visibility to 'on'
                app.BandpassBWDropDown.Visible = 'off';    % Set visibility to 'off'
                app.NopersegmentDropDown.Editable = 'on';  % Set Editable to 'on'
                app.BandpassBWDropDown.Editable = 'off';    % Set Editable to 'off'
            case 'hilbert'
                app.NopersegmentDropDown.Visible = 'off';   % Set visibility to 'off'
                app.BandpassBWDropDown.Visible = 'on';      % Set visibility to 'on'
                app.NopersegmentDropDown.Editable = 'off';  % Set Editable to 'off'
                app.BandpassBWDropDown.Editable = 'on';     % Set Editable to 'on'
           end
        end

        % Value changed function: ExperimenterEditField
        function ExperimenterEditFieldValueChanged(app, event)
            app.Experimenter = app.ExperimenterEditField.Value;
            %app.Experimenter = Experimenter;
            
        end

        % Value changed function: SubjectEditField
        function SubjectEditFieldValueChanged(app, event)
            app.subject =app.SubjectEditField.Value;
            
        end

        % Value changed function: SessionEditField
        function SessionEditFieldValueChanged(app, event)
            app.session = app.SessionEditField.Value;
            
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            app.Date = app.DateDatePicker.Value;
            
        end

        % Value changed function: ParadigmEditField
        function ParadigmEditFieldValueChanged(app, event)
            app.paradigm = app.ParadigmEditField.Value;
            
        end

        % Value changed function: SurgeonEditField
        function SurgeonEditFieldValueChanged(app, event)
            app.surgeon = app.SurgeonEditField.Value;
            
        end

        % Value changed function: SurgeryDateDatePicker
        function SurgeryDateDatePickerValueChanged(app, event)
            app.surgeryDate = app.SurgeryDateDatePicker.Value;
            
        end

        % Value changed function: VolumenLEditField
        function VolumenLEditFieldValueChanged(app, event)
            app.volume = app.VolumenLEditField.Value;
        end

        % Value changed function: APEditField
        function APEditFieldValueChanged(app, event)
           
        end

        % Value changed function: MLEditField
        function MLEditFieldValueChanged(app, event)
            
        end

        % Value changed function: DVEditField
        function DVEditFieldValueChanged(app, event)
         
           
        end

        % Value changed function: RegionEditField
        function RegionEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: FiberAPEditField
        function FiberAPEditFieldValueChanged(app, event)
           
            
        end

        % Value changed function: FiberMLEditField
        function FiberMLEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: FiberDVEditField
        function FiberDVEditFieldValueChanged(app, event)
            
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            app.diameter = app.DiameterEditField.Value;
            
        end

        % Value changed function: TimeoffsetsEditField
        function TimeoffsetsEditFieldValueChanged(app, event)
            app.dT = app.TimeoffsetsEditField.Value;
            
        end

        % Value changed function: SamplingHzEditField
        function SamplingHzEditFieldValueChanged(app, event)
            app.sampleFreq = app.SamplingHzEditField.Value;
            
        end

        % Value changed function: DownsamplingHzEditField
        function DownsamplingHzEditFieldValueChanged(app, event)
             app.downsampleFreq = app.DownsamplingHzEditField.Value;
            
        end

        % Value changed function: ZwindowEditField
        function ZwindowEditFieldValueChanged(app, event)
            app.z = app.ZwindowEditField.Value;
            
        end

        % Callback function
        function TransformDropDownOpening(app, event)
            app.transform = app.TransformDropDown.Value;
            
        end

        % Value changed function: NopersegmentDropDown
        function NopersegmentDropDownValueChanged(app, event)
            app.Noperseg = str2double(app.NopersegmentDropDown.Value);
            app.NoOverlap = str2double(NoperSeg/2);           
        end

        % Value changed function: BandpassBWDropDown
        function BandpassBWDropDownValueChanged(app, event)
           app.BP = app.BandpassBWDropDown.Value;
            
        end

        % Value changed function: GreencarrierfreqEditField
        function GreencarrierfreqEditFieldValueChanged(app, event)
            
        end

        % Value changed function: RedcarrierfreqEditField
        function RedcarrierfreqEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: TotalchannelsEditField
        function TotalchannelsEditFieldValueChanged(app, event)
            app.total_chan = app.TotalchannelsEditField.Value;
            
        end

        % Value changed function: GreenexcitationwavelengthEditField
        function GreenexcitationwavelengthEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: GreenemissionwavelengthEditField
        function GreenemissionwavelengthEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: RedexcitationwavelengthEditField
        function RedexcitationwavelengthEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: RedemissionwavelengthEditField
        function RedemissionwavelengthEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: GreenPhotomEditField
        function GreenPhotomEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: RedPhotomEditField
        function RedPhotomEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: GreenCarrierEditField
        function GreenCarrierEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: RedCarrierEditField
        function RedCarrierEditFieldValueChanged(app, event)

            
        end

        % Button pushed function: AddChannelDetailstoRightButton
        function AddChannelDetailstoRightButtonPushed(app, event)
            green_excitation = app.GreenexcitationwavelengthEditField.Value;
            red_excitation = app.RedexcitationwavelengthEditField.Value;
            green_emission = app.GreenemissionwavelengthEditField.Value;
            red_emission = app.RedemissionwavelengthEditField.Value;
            g_photom = app.GreenPhotomEditField.Value;
            r_photom = app.RedPhotomEditField.Value;
            g_carrier = app.GreenCarrierEditField.Value;
            r_carrier = app.RedCarrierEditField.Value;
        
            % Create a dynamic structure to store the data
            rightSignal = struct();
            rightSignal.right_green_excitation = green_excitation;
            rightSignal.right_red_excitation = red_excitation;
            rightSignal.right_green_emission = green_emission;
            rightSignal.right_red_emission = red_emission;
            rightSignal.right_g_photom = g_photom;
            rightSignal.right_r_photom = r_photom;
            rightSignal.right_g_carrier = g_carrier;
            rightSignal.right_r_carrier = r_carrier;
        
            % Store the structure in the app data
            app.rightSignal = rightSignal;
            
        end

        % Button pushed function: AddChannelDetailstoLeftButton
        function AddChannelDetailstoLeftButtonPushed(app, event)
            green_excitation = app.GreenexcitationwavelengthEditField.Value;
            red_excitation = app.RedexcitationwavelengthEditField.Value;
            green_emission = app.GreenemissionwavelengthEditField.Value;
            red_emission = app.RedemissionwavelengthEditField.Value;
            g_photom = app.GreenPhotomEditField.Value;
            r_photom = app.RedPhotomEditField.Value;
            g_carrier = app.GreenCarrierEditField.Value;
            r_carrier = app.RedCarrierEditField.Value;
        
            % Create struct to store the data
            leftSignal = struct();
            leftSignal.left_green_excitation = green_excitation;
            leftSignal.left_red_excitation = red_excitation;
            leftSignal.left_green_emission = green_emission;
            leftSignal.left_red_emission = red_emission;
            leftSignal.left_g_photom = g_photom;
            leftSignal.left_r_photom = r_photom;
            leftSignal.left_g_carrier = g_carrier;
            leftSignal.left_r_carrier = r_carrier;
        
            % Store the structure in the app data
            app.leftSignal = leftSignal;
            
        end

        % Button pushed function: AddrightinjectionButton
        function AddrightinjectionButtonPushed(app, event)
            serotype = app.SerotypeDropDown.Value;
            recombinase = app.RecombinaseDependencyDropDown.Value;
            protein = app.ProteinDropDown.Value;
            virus = strcat(serotype, '-', recombinase, '-', protein);
            sensor_protein = app.SensorProteinDropDown.Value;
            app.volume = app.VolumenLEditField.Value;
            AP = app.APEditField.Value;
            ML = app.MLEditField.Value;
            DV = app.DVEditField.Value;
            
            %create struct to store
            rightInjection = struct();
            rightInjection.serotype = serotype;
            rightInjection.recombinase = recombinase;
            rightInjection.protein = protein;
            rightInjection.virus = virus;
            rightInjection.sensor_protein = sensor_protein;
            rightInjection.volume = app.volume;
            rightInjection.AP = AP;
            rightInjection.ML = ML;
            rightInjection.DV = DV;
            
            app.rightInjection = rightInjection;
            
            
        end

        % Button pushed function: AddleftinjectionButton
        function AddleftinjectionButtonPushed(app, event)
            serotype = app.SerotypeDropDown.Value;
            recombinase = app.RecombinaseDependencyDropDown.Value;
            protein = app.ProteinDropDown.Value;
            virus = strcat(serotype, '-', recombinase, '-', protein);
            sensor_protein = app.SensorProteinDropDown.Value;
            app.volume = app.VolumenLEditField.Value;
            AP = app.APEditField.Value;
            ML = app.MLEditField.Value;
            DV = app.DVEditField.Value;
            
            %create struct to store
            leftInjection = struct();
            leftInjection.serotype = serotype;
            leftInjection.recombinase = recombinase;
            leftInjection.protein = protein;
            leftInjection.virus = virus;
            leftInjection.sensor_protein = sensor_protein;
            leftInjection.volume = app.volume;
            leftInjection.AP = AP;
            leftInjection.ML = ML;
            leftInjection.DV = DV;
            
            app.leftInjection = leftInjection;
            
        end

        % Button pushed function: AddrightimplantationButton
        function AddrightimplantationButtonPushed(app, event)
            region = app.RegionEditField.Value;
            fiberAP = app.FiberAPEditField.Value;
            fiberML = app.FiberMLEditField.Value;
            fiberDV = app.FiberDVEditField.Value;
            
            %create struct
            rightImplant = struct();
            rightImplant.region = region;
            rightImplant.fiberAP = fiberAP;
            rightImplant.fiberML = fiberML;
            rightImplant.fiberDV = fiberDV;
            
            app.rightImplant = rightImplant;
            
        end

        % Button pushed function: AddleftimplantationButton
        function AddleftimplantationButtonPushed(app, event)
            region = app.RegionEditField.Value;
            fiberAP = app.FiberAPEditField.Value;
            fiberML = app.FiberMLEditField.Value;
            fiberDV = app.FiberDVEditField.Value;
            
            %create struct
            leftImplant = struct();
            leftImplant.region = region;
            leftImplant.fiberAP = fiberAP;
            leftImplant.fiberML = fiberML;
            leftImplant.fiberDV = fiberDV;
            
            app.leftImplant = leftImplant;
        end

        % Button pushed function: AddtoRightHemisphereButton
        function AddtoRightHemisphereButtonPushed(app, event)
            carrier_frequency_g = app.GreencarrierfreqEditField.Value;
            carrier_frequency_r = app.RedcarrierfreqEditField.Value;
            
            %create struct
            carrierRight = struct();
            carrierRight.carrier_g = carrier_frequency_g;
            carrierRight.carrier_r = carrier_frequency_r;
            
            
            app.carrierRight = carrierRight;
        end

        % Button pushed function: AddtoLeftHemisphereButton
        function AddtoLeftHemisphereButtonPushed(app, event)
            carrier_frequency_g = app.GreencarrierfreqEditField.Value;
            carrier_frequency_r = app.RedcarrierfreqEditField.Value;
            
            %create struct
            carrierLeft = struct();
            carrierLeft.carrier_g = carrier_frequency_g;
            carrierLeft.carrier_r = carrier_frequency_r;
            
            app.carrierLeft = carrierLeft;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
                
                
                %% make matlab struct for toml_generation

                complete_struct = struct();
                
                complete_struct.Experiment = struct('experimenter', app.Experimenter,...
                    'subject', app.subject, 'paradigm', app.paradigm, 'start_date', app.Date,...
                    'hemisphere', "both");
                complete_struct.VirusInjection = struct('experimenter', app.surgeon,...
                    'date', app.surgeryDate);
                
                complete_struct.VirusInjection.left = struct('virus', app.leftInjection.virus,...
                    'sensor_protein', app.leftInjection.sensor_protein, 'volume', app.leftInjection.volume, 'injection_time',...
                    '', 'rate_of_injection', '', 'wait_time', '', 'brain_region',...
                    '', 'notes', '', 'ap', app.leftInjection.AP, 'ap_ref', 'bregma',...
                    'ml', app.leftInjection.ML, 'ml_ref', 'sagittal_suture', 'dv', app.leftInjection.DV, 'dv_ref', 'dura');
                complete_struct.VirusInjection.right = struct('virus', app.rightInjection.virus,...
                    'sensor_protein', app.rightInjection.sensor_protein, 'volume', app.rightInjection.volume, 'injection_time',...
                    '', 'rate_of_injection', '', 'wait_time', '', 'brain_region',...
                    '', 'notes', '', 'ap', app.rightInjection.AP, 'ap_ref', 'bregma',...
                    'ml', app.rightInjection.ML, 'ml_ref', 'sagittal_suture', 'dv', app.rightInjection.DV, 'dv_ref', 'dura');
                
                complete_struct.Fiber = struct('light_source', 'LED', 'fiber_type',...
                    'doric', 'fiber_diameter', app.diameter);
                
                complete_struct.Fiber.implantation = struct('date', app.surgeryDate, 'surgeon',...
                    app.surgeon);
                complete_struct.Fiber.implantation.left = struct('brain_region', app.leftImplant.region,...
                    'ap', app.leftImplant.fiberAP, 'ap_ref', 'bregma', 'ml', app.leftImplant.fiberML, 'ml_ref', 'sagittal_suture',...
                    'dv', app.leftImplant.fiberDV, 'dv_ref', 'dura', 'notes', '');
                complete_struct.Fiber.implantation.right = struct('brain_region', app.rightImplant.region,...
                    'ap', app.rightImplant.fiberAP, 'ap_ref', 'bregma', 'ml', app.rightImplant.fiberML, 'ml_ref', 'sagittal_suture',...
                    'dv', app.rightImplant.fiberDV, 'dv_ref', 'dura', 'notes', '');
                
                complete_struct.Processing_Parameters = struct('behavior_offset', app.dT,...
                    'final_z', 'false', 'z_window', app.z, 'bandpass_bandwidth', app.BP,...
                    'sampling_frequency', app.sampleFreq, 'downsample_frequency',...
                    app.downsampleFreq, 'transform', app.transform, 'no_per_segment', app.Noperseg,...
                    'no_overlap', app.NoOverlap);
                
                complete_struct.Processing_Parameters.left = struct('carrier_frequency_g',...
                    app.carrierLeft.carrier_g, 'carrier_frequency_r', app.carrierLeft.carrier_r);
                complete_struct.Processing_Parameters.right = struct('carrier_frequency_g',...
                    app.carrierRight.carrier_g, 'carrier_frequency_r', app.carrierRight.carrier_r);
                
                complete_struct.Signal_Indices = struct('total_channels', app.total_chan);
                
                left_green_emission = app.leftSignal.left_green_emission;
                left_red_emission = app.leftSignal.left_red_emission;
                left_emission_wl = sprintf('green = %d, red = %d', left_green_emission, left_red_emission);
                right_green_emission = app.rightSignal.right_green_emission;
                right_red_emission = app.rightSignal.right_red_emission;
                right_emission_wl = sprintf('green = %d, red = %d', right_green_emission, right_red_emission);
                
                left_green_excitation = app.leftSignal.left_green_excitation;
                left_red_excitation = app.leftSignal.left_red_excitation;
                left_excitation_wl = sprintf('green = %d, red = %d', left_green_excitation, left_red_excitation);
                right_green_excitation = app.rightSignal.right_green_excitation;
                right_red_excitation = app.rightSignal.right_red_excitation;
                right_excitation_wl = sprintf('green = %d, red = %d', right_green_excitation, right_red_excitation);
                
                complete_struct.Signal_Indices.left = struct('emission_wavelength',...
                    left_emission_wl, 'excitation_wavelength', left_excitation_wl,...
                    'photom_g', app.leftSignal.left_g_photom, 'photom_r', app.leftSignal.left_r_photom, 'carrier_g',...
                     app.leftSignal.left_g_carrier,...
                    'carrier_r', app.leftSignal.left_r_carrier);
                complete_struct.Signal_Indices.right = struct('emission_wavelength',...
                    right_emission_wl, 'excitation_wavelength', right_excitation_wl,...
                    'photom_g', app.rightSignal.right_g_photom, 'photom_r', app.rightSignal.right_r_photom, 'carrier_g',...
                     app.rightSignal.right_g_carrier,...
                    'carrier_r', app.rightSignal.right_r_carrier);
                
                json_data = savejson('', complete_struct);
                filename = [app.subject, '_',...
                    app.session, '_', 'output.json'];
                fid = fopen(filename, 'w');
                fprintf(fid, '%s', json_data);
                fclose(fid);
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectEditFieldValueChanged, true);
            app.SessionEditFieldLabel = uilabel(app.UIFigure);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.UIFigure, 'text');
            app.SessionEditField.ValueChangedFcn = createCallbackFcn(app, @SessionEditFieldValueChanged, true);
            app.ExperimenterEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimenterEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimenterEditFieldValueChanged, true);
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.DateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.ParadigmEditFieldLabel = uilabel(app.UIFigure);
            app.ParadigmEditFieldLabel.Text = 'Paradigm';
            app.ParadigmEditField = uieditfield(app.UIFigure, 'text');
            app.ParadigmEditField.ValueChangedFcn = createCallbackFcn(app, @ParadigmEditFieldValueChanged, true);
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.SurgeonEditFieldLabel = uilabel(app.UIFigure);
            app.SurgeonEditFieldLabel.Text = 'Surgeon';
            app.SurgeonEditField = uieditfield(app.UIFigure, 'text');
            app.SurgeonEditField.ValueChangedFcn = createCallbackFcn(app, @SurgeonEditFieldValueChanged, true);
            app.SurgeryDateDatePickerLabel = uilabel(app.UIFigure);
            app.SurgeryDateDatePickerLabel.Text = 'Surgery Date';
            app.SurgeryDateDatePicker = uidatepicker(app.UIFigure);
            app.SurgeryDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SurgeryDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @SurgeryDateDatePickerValueChanged, true);
            app.SerotypeDropDownLabel = uilabel(app.UIFigure);
            app.SerotypeDropDownLabel.Text = 'Serotype';
            app.SerotypeDropDown = uidropdown(app.UIFigure);
            app.SerotypeDropDown.Items = {'None', 'AAV1', 'AAV5', 'AAV9', 'rAAV'};
            app.SerotypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SerotypeDropDownValueChanged, true);
            app.SerotypeDropDown.Value = 'None';
            app.RecombinaseDependencyDropDownLabel = uilabel(app.UIFigure);
            app.RecombinaseDependencyDropDownLabel.Text = 'Recombinase Dependency';
            app.RecombinaseDependencyDropDown = uidropdown(app.UIFigure);
            app.RecombinaseDependencyDropDown.Items = {'None', 'DIO', 'fDIO'};
            app.RecombinaseDependencyDropDown.ValueChangedFcn = createCallbackFcn(app, @RecombinaseDependencyDropDownValueChanged, true);
            app.RecombinaseDependencyDropDown.Value = 'None';
            app.ProteinDropDownLabel = uilabel(app.UIFigure);
            app.ProteinDropDownLabel.Text = 'Protein';
            app.ProteinDropDown = uidropdown(app.UIFigure);
            app.ProteinDropDown.Items = {'None', 'GFP', 'tdTom', 'ChR2-EYFP', 'GCaMP6s', 'GCaMP6f', ''};
            app.ProteinDropDown.ValueChangedFcn = createCallbackFcn(app, @ProteinDropDownValueChanged, true);
            app.ProteinDropDown.Value = 'None';
            app.SensorProteinDropDownLabel = uilabel(app.UIFigure);
            app.SensorProteinDropDownLabel.Text = 'Sensor Protein';
            app.SensorProteinDropDown = uidropdown(app.UIFigure);
            app.SensorProteinDropDown.Items = {'None', 'green = GCaMP', 'red = RCaMP', 'green = GCaMP, red = RCaMP', ''};
            app.SensorProteinDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorProteinDropDownValueChanged, true);
            app.SensorProteinDropDown.Value = 'None';
            app.VolumenLEditFieldLabel = uilabel(app.UIFigure);
            app.VolumenLEditFieldLabel.Text = 'Volume (nL)';
            app.VolumenLEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumenLEditField.ValueChangedFcn = createCallbackFcn(app, @VolumenLEditFieldValueChanged, true);
            app.APEditFieldLabel = uilabel(app.UIFigure);
            app.APEditFieldLabel.Text = 'A/P';
            app.APEditField = uieditfield(app.UIFigure, 'numeric');
            app.APEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditFieldLabel = uilabel(app.UIFigure);
            app.MLEditFieldLabel.Text = 'M/L';
            app.MLEditField = uieditfield(app.UIFigure, 'numeric');
            app.MLEditField.ValueChangedFcn = createCallbackFcn(app, @MLEditFieldValueChanged, true);
            app.DVEditFieldLabel = uilabel(app.UIFigure);
            app.DVEditFieldLabel.Text = 'D/V';
            app.DVEditField = uieditfield(app.UIFigure, 'numeric');
            app.DVEditField.ValueChangedFcn = createCallbackFcn(app, @DVEditFieldValueChanged, true);
            app.VirusInjectionLabel = uilabel(app.UIFigure);
            app.VirusInjectionLabel.Text = 'Virus Injection';
            app.FiberImplantationLabel = uilabel(app.UIFigure);
            app.FiberImplantationLabel.Text = 'Fiber Implantation';
            app.sabatinidatajointmetafilegeneratorLabel = uilabel(app.UIFigure);
            app.sabatinidatajointmetafilegeneratorLabel.Text = 'sabatini-datajoint meta file generator';
            app.AddrightinjectionButton = uibutton(app.UIFigure, 'push');
            app.AddrightinjectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddrightinjectionButtonPushed, true);
            app.AddrightinjectionButton.Text = 'Add right injection';
            app.RegionEditFieldLabel = uilabel(app.UIFigure);
            app.RegionEditFieldLabel.Text = 'Region';
            app.RegionEditField = uieditfield(app.UIFigure, 'text');
            app.RegionEditField.ValueChangedFcn = createCallbackFcn(app, @RegionEditFieldValueChanged, true);
            app.AddrightimplantationButton = uibutton(app.UIFigure, 'push');
            app.AddrightimplantationButton.ButtonPushedFcn = createCallbackFcn(app, @AddrightimplantationButtonPushed, true);
            app.AddrightimplantationButton.Text = {'Add right implantation'; ''};
            app.FiberAPEditFieldLabel = uilabel(app.UIFigure);
            app.FiberAPEditFieldLabel.Text = ' Fiber A/P';
            app.FiberAPEditField = uieditfield(app.UIFigure, 'numeric');
            app.FiberAPEditField.ValueChangedFcn = createCallbackFcn(app, @FiberAPEditFieldValueChanged, true);
            app.FiberMLEditFieldLabel = uilabel(app.UIFigure);
            app.FiberMLEditFieldLabel.Text = 'Fiber M/L';
            app.FiberMLEditField = uieditfield(app.UIFigure, 'numeric');
            app.FiberMLEditField.ValueChangedFcn = createCallbackFcn(app, @FiberMLEditFieldValueChanged, true);
            app.FiberDVEditFieldLabel = uilabel(app.UIFigure);
            app.FiberDVEditFieldLabel.Text = 'Fiber D/V';
            app.FiberDVEditField = uieditfield(app.UIFigure, 'numeric');
            app.FiberDVEditField.ValueChangedFcn = createCallbackFcn(app, @FiberDVEditFieldValueChanged, true);
            app.DiameterEditFieldLabel = uilabel(app.UIFigure);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.ProcessingParamatersLabel = uilabel(app.UIFigure);
            app.ProcessingParamatersLabel.Text = 'Processing Paramaters';
            app.TimeoffsetsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeoffsetsEditFieldLabel.Text = 'Time offset (s)';
            app.TimeoffsetsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeoffsetsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeoffsetsEditFieldValueChanged, true);
            app.SamplingHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingHzEditFieldLabel.Text = 'Sampling Hz';
            app.SamplingHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingHzEditFieldValueChanged, true);
            app.DownsamplingHzEditFieldLabel = uilabel(app.UIFigure);
            app.DownsamplingHzEditFieldLabel.Text = 'Downsampling Hz';
            app.DownsamplingHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.DownsamplingHzEditField.ValueChangedFcn = createCallbackFcn(app, @DownsamplingHzEditFieldValueChanged, true);
            app.ZwindowEditFieldLabel = uilabel(app.UIFigure);
            app.ZwindowEditFieldLabel.Text = 'Z window';
            app.ZwindowEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZwindowEditField.ValueChangedFcn = createCallbackFcn(app, @ZwindowEditFieldValueChanged, true);
            app.TransformDropDownLabel = uilabel(app.UIFigure);
            app.TransformDropDownLabel.Text = 'Transform';
            app.TransformDropDown = uidropdown(app.UIFigure);
            app.TransformDropDown.Items = {'Photom Analysis', 'spectrogram', 'hilbert', ''};
            app.TransformDropDown.ValueChangedFcn = createCallbackFcn(app, @TransformDropDownValueChanged, true);
            app.TransformDropDown.Value = 'Photom Analysis';
            app.NopersegmentDropDownLabel = uilabel(app.UIFigure);
            app.NopersegmentDropDownLabel.Text = 'No. per segment';
            app.NopersegmentDropDown = uidropdown(app.UIFigure);
            app.NopersegmentDropDown.Items = {'216', '966'};
            app.NopersegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @NopersegmentDropDownValueChanged, true);
            app.NopersegmentDropDown.Value = '216';
            app.BandpassBWDropDownLabel = uilabel(app.UIFigure);
            app.BandpassBWDropDownLabel.Text = 'Bandpass BW';
            app.BandpassBWDropDown = uidropdown(app.UIFigure);
            app.BandpassBWDropDown.Items = {'20', '50'};
            app.BandpassBWDropDown.ValueChangedFcn = createCallbackFcn(app, @BandpassBWDropDownValueChanged, true);
            app.BandpassBWDropDown.Value = '20';
            app.GreencarrierfreqEditFieldLabel = uilabel(app.UIFigure);
            app.GreencarrierfreqEditFieldLabel.Text = 'Green carrier freq';
            app.GreencarrierfreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreencarrierfreqEditField.ValueChangedFcn = createCallbackFcn(app, @GreencarrierfreqEditFieldValueChanged, true);
            app.RedcarrierfreqEditFieldLabel = uilabel(app.UIFigure);
            app.RedcarrierfreqEditFieldLabel.Text = 'Red carrier freq';
            app.RedcarrierfreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedcarrierfreqEditField.ValueChangedFcn = createCallbackFcn(app, @RedcarrierfreqEditFieldValueChanged, true);
            app.AddtoRightHemisphereButton = uibutton(app.UIFigure, 'push');
            app.AddtoRightHemisphereButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoRightHemisphereButtonPushed, true);
            app.AddtoRightHemisphereButton.Text = 'Add to Right Hemisphere';
            app.AddtoLeftHemisphereButton = uibutton(app.UIFigure, 'push');
            app.AddtoLeftHemisphereButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoLeftHemisphereButtonPushed, true);
            app.AddtoLeftHemisphereButton.Text = 'Add to Left Hemisphere';
            app.ChannelDetailsLabel = uilabel(app.UIFigure);
            app.ChannelDetailsLabel.Text = 'Channel Details';
            app.TotalchannelsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalchannelsEditFieldLabel.Text = 'Total channels';
            app.TotalchannelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalchannelsEditField.ValueChangedFcn = createCallbackFcn(app, @TotalchannelsEditFieldValueChanged, true);
            app.GreenexcitationwavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.GreenexcitationwavelengthEditFieldLabel.Text = 'Green excitation wavelength';
            app.GreenexcitationwavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenexcitationwavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @GreenexcitationwavelengthEditFieldValueChanged, true);
            app.GreenemissionwavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.GreenemissionwavelengthEditFieldLabel.Text = 'Green emission wavelength';
            app.GreenemissionwavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenemissionwavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @GreenemissionwavelengthEditFieldValueChanged, true);
            app.RedexcitationwavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.RedexcitationwavelengthEditFieldLabel.Text = 'Red excitation wavelength';
            app.RedexcitationwavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedexcitationwavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @RedexcitationwavelengthEditFieldValueChanged, true);
            app.RedemissionwavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.RedemissionwavelengthEditFieldLabel.Text = 'Red emission wavelength';
            app.RedemissionwavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedemissionwavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @RedemissionwavelengthEditFieldValueChanged, true);
            app.SignalIndiceszeroindexednone1Label = uilabel(app.UIFigure);
            app.SignalIndiceszeroindexednone1Label.Text = 'Signal Indices (zero indexed, none = -1)';
            app.GreenPhotomEditFieldLabel = uilabel(app.UIFigure);
            app.GreenPhotomEditFieldLabel.Text = 'Green Photom';
            app.GreenPhotomEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenPhotomEditField.ValueChangedFcn = createCallbackFcn(app, @GreenPhotomEditFieldValueChanged, true);
            app.RedPhotomEditFieldLabel = uilabel(app.UIFigure);
            app.RedPhotomEditFieldLabel.Text = 'Red Photom';
            app.RedPhotomEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedPhotomEditField.ValueChangedFcn = createCallbackFcn(app, @RedPhotomEditFieldValueChanged, true);
            app.GreenCarrierEditFieldLabel = uilabel(app.UIFigure);
            app.GreenCarrierEditFieldLabel.Text = 'Green Carrier';
            app.GreenCarrierEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenCarrierEditField.ValueChangedFcn = createCallbackFcn(app, @GreenCarrierEditFieldValueChanged, true);
            app.RedCarrierEditFieldLabel = uilabel(app.UIFigure);
            app.RedCarrierEditFieldLabel.Text = 'Red Carrier';
            app.RedCarrierEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedCarrierEditField.ValueChangedFcn = createCallbackFcn(app, @RedCarrierEditFieldValueChanged, true);
            app.AddChannelDetailstoRightButton = uibutton(app.UIFigure, 'push');
            app.AddChannelDetailstoRightButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelDetailstoRightButtonPushed, true);
            app.AddChannelDetailstoRightButton.Text = 'Add Channel Details to Right';
            app.AddChannelDetailstoLeftButton = uibutton(app.UIFigure, 'push');
            app.AddChannelDetailstoLeftButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelDetailstoLeftButtonPushed, true);
            app.AddChannelDetailstoLeftButton.Text = 'Add Channel Details to Left';
            app.AddleftinjectionButton = uibutton(app.UIFigure, 'push');
            app.AddleftinjectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddleftinjectionButtonPushed, true);
            app.AddleftinjectionButton.Text = 'Add left injection';
            app.AddleftimplantationButton = uibutton(app.UIFigure, 'push');
            app.AddleftimplantationButton.ButtonPushedFcn = createCallbackFcn(app, @AddleftimplantationButtonPushed, true);
            app.AddleftimplantationButton.Text = {'Add left implantation'; ''};
            app.GeneratesaJSONfilethatwillthenLabel = uilabel(app.UIFigure);
            app.GeneratesaJSONfilethatwillthenLabel.Text = {'Let''s make a JSON file. We will then'; 'run convertTOML.py to convert the JSON'; 'to TOML in python.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = make_toml

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end